classdef SystemUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SetScenariosMenu                matlab.ui.container.Menu
        S1Menu                          matlab.ui.container.Menu
        RunS1Menu                       matlab.ui.container.Menu
        S2Menu                          matlab.ui.container.Menu
        RunS2Menu                       matlab.ui.container.Menu
        S3Menu                          matlab.ui.container.Menu
        RunS3Menu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        InsertChangesTab                matlab.ui.container.Tab
        PopulationGrowthEditFieldLabel  matlab.ui.control.Label
        PopulationGrowthEditField       matlab.ui.control.NumericEditField
        InsertChangesFor2030Label       matlab.ui.control.Label
        CloseButton_2                   matlab.ui.control.Button
        ChangeInElectricityConsumptionEditFieldLabel  matlab.ui.control.Label
        ChangeInElectricityConsumptionEditField  matlab.ui.control.NumericEditField
        ReduceBeefConsumptionEditFieldLabel  matlab.ui.control.Label
        ReduceBeefConsumptionEditField  matlab.ui.control.NumericEditField
        EnergyFromRenewableResourcesEditFieldLabel  matlab.ui.control.Label
        EnergyFromRenewableResourcesEditField  matlab.ui.control.NumericEditField
        ScenariosTable                  matlab.ui.container.Tab
        ScenariosTableLabel             matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CloseButton                     matlab.ui.control.Button
        ScenariosandValuesTab           matlab.ui.container.Tab
        ValuesTable                     matlab.ui.control.Table
        ScenariosandValuesLabel         matlab.ui.control.Label
        AddScenarioTab                  matlab.ui.container.Tab
        SensitivityAnalysisTab          matlab.ui.container.Tab
        SesitivityAnalysisLabel         matlab.ui.control.Label
        RunOnlyOneScenarioDropDownLabel  matlab.ui.control.Label
        RunOnlyOneScenarioDropDown      matlab.ui.control.DropDown
        RunAllButOneScenarioDropDownLabel  matlab.ui.control.Label
        RunAllButOneScenarioDropDown    matlab.ui.control.DropDown
        RunSensitivityAnalysisButton    matlab.ui.control.Button
        CloseButton_3                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        scenariosTable = (zeros(19,14)); % Description
        EmissionsByYearsPopulationGrowth;
        ConsumptionAmountsPopulationGrowth;
        Years = 14;
        ComboTableFor2030;
        ME = MException('MyComponent:noSuchVariable', ...
            'Not a valid number!');
    end
    
    methods (Access = public)
        
        function UpdatedTable = ChangeTableByScanrios(app, table)
            UpdatedTable = zeros(height(table),width(table));
            for i = 1:height(table)
                newData = table(i,width(table));
                if(newData < 0)
                    throw(app.ME);
                else
                    temp = ones(1,14);
                    switch i
                        case 6
                            temp(1) = 0.027;
                        case 7
                            temp(1) = 0.631;
                        case {11,13,14,15,16,17}
                            temp(1) = 0;
                        otherwise
                            temp(1) = 1;
                    end
                    if(temp(1) == 0)
                        Rate = 0;
                        
                    elseif (temp(1) > 0 && temp(1) < 1)
                        temp(1) = 1+temp(1);
                        temp(14) = 1+newData;
                        Rate = nthroot(temp(14)/temp(1),14);
                        for j = 2:13
                            temp(j) = temp(1)*((Rate)^(j));
                        end
                    end
                    temp(14) = newData;
                    
                    UpdatedTable(i,:) = temp;
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.scenariosTable = ones(19,14);
            app.UITable.Data = app.scenariosTable;
            RowNames = {'Growth in Population', 'Electricity Saving Scenario', '3', 'Reducing Beef Consumption', '5', 'Change In Energy Consumption From Renewable Energies', '7','Electricity Saving','9','10', '11', '12', 'Transition To Public Transportation', 'Transition to Electric Car', 'Transition to Electric Van', 'Transition to Electric Truck', 'Transition to Electric Bus','18', 'Water Saving'};
            app.UITable.RowName = RowNames;
            app.RunOnlyOneScenarioDropDown.Items = RowNames;
            app.RunAllButOneScenarioDropDown.Items = RowNames;
            ColumnNames = zeros(1, 14);
            for i = 1:width(ColumnNames)
                ColumnNames(i) = 2016+i;
            end
            app.UITable.ColumnName = ColumnNames;
            app.UITable.ColumnEditable = true;
            app.UITable.Data(6,:) = 0.027*ones(1,14);
            app.UITable.Data(7, :) = 0.631*ones(1,14);
            app.UITable.Data(13, :) = zeros(1,14);
            app.UITable.Data(13, :) = zeros(1,14);
            
            app.UITable.Data(14, :) = zeros(1,14);
            app.UITable.Data(15, :) = zeros(1,14);
            app.UITable.Data(16, :) = zeros(1,14);
            app.UITable.Data(17, :) = zeros(1,14);

            app.ComboTableFor2030 = readtable("התרחישים וערכיהם.xlsx", 'Range','B1:D20');
            app.ValuesTable.RowName = RowNames;
            app.ValuesTable.ColumnName = {'S1', 'S2', 'S3'};
            app.ValuesTable.Data = app.ComboTableFor2030;
            app.ValuesTable.ColumnEditable = true;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.scenariosTable = app.UITable.Data;
            Data = "Data.xlsx";
            app.UITable.Data = ChangeTableByScanrios(app,app.scenariosTable);
            addpath("Scenarios\");
            tempTable = app.UITable.Data;
            [app.EmissionsByYearsPopulationGrowth, app.ConsumptionAmountsPopulationGrowth] = FullScenario(Data,tempTable,app.Years);
            f = uifigure;
            f2 = uifigure;
            t1 = uitable(f,"Data",app.EmissionsByYearsPopulationGrowth);
            t2 = uitable(f2, "Data", app.ConsumptionAmountsPopulationGrowth);
            
        end

        % Menu selected function: S1Menu
        function S1MenuSelected(app, event)
            
        end

        % Menu selected function: RunS1Menu
        function RunS1MenuSelected(app, event)
            for i = 1:height(app.scenariosTable)
                switch i
                    case {6,7,11,13,14,15,16,17}
                        app.scenariosTable(i,width(app.scenariosTable)) = app.ComboTableFor2030{i,2};
                    otherwise
                        app.scenariosTable(i,width(app.scenariosTable)) = 1+app.ComboTableFor2030{i,2};
                end
            end
            app.UITable.Data = ChangeTableByScanrios(app,app.scenariosTable);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            
        end

        % Button pushed function: CloseButton, CloseButton_2, 
        % CloseButton_3
        function CloseButtonPushed(app, event)
            closereq;
        end

        % Value changed function: PopulationGrowthEditField
        function PopulationGrowthEditFieldValueChanged(app, event)
            value = app.PopulationGrowthEditField.Value;
            app.scenariosTable(1,app.Years) = 1+value;
            FutureValue = app.scenariosTable(1,app.Years);
            Rate = nthroot(FutureValue/app.scenariosTable(1, 1),app.Years-1);
            for i = 2:app.Years-1
                app.scenariosTable(1, i) = app.scenariosTable(1, 1)*((Rate)^(i-1));
            end
            
            app.UITable.Data(1,:) = app.scenariosTable(1,:);
            
        end

        % Value changed function: 
        % ChangeInElectricityConsumptionEditField
        function ChangeInElectricityConsumptionEditFieldValueChanged(app, event)
            value = app.ChangeInElectricityConsumptionEditField.Value;
            app.scenariosTable(2,app.Years) = 1+value;
            FutureValue = app.scenariosTable(2,app.Years);
            Rate = nthroot(FutureValue/app.scenariosTable(1, 1),app.Years-1);
            for i = 2:app.Years-1
                app.scenariosTable(2, i) = app.scenariosTable(2, 1)*((Rate)^(i-1));
            end
            app.UITable.Data(2,:) = app.scenariosTable(2,:);
        end

        % Value changed function: ReduceBeefConsumptionEditField
        function ReduceBeefConsumptionEditFieldValueChanged(app, event)
            value = app.ReduceBeefConsumptionEditField.Value;
            app.scenariosTable(4,app.Years) = 1-value;
            FutureValue = app.scenariosTable(4,app.Years);
            Rate = nthroot(FutureValue/app.scenariosTable(4, 1),app.Years-1);
            for i = 2:app.Years-1
                app.scenariosTable(4, i) = app.scenariosTable(4, 1)*((Rate)^(i-1));
            end
            app.UITable.Data(4,:) = app.scenariosTable(4,:);
        end

        % Value changed function: 
        % EnergyFromRenewableResourcesEditField
        function EnergyFromRenewableResourcesEditFieldValueChanged(app, event)
            value = app.EnergyFromRenewableResourcesEditField.Value;
            app.scenariosTable(6,app.Years) = 1+value;
            FutureValue = app.scenariosTable(6,app.Years);
            app.scenariosTable(6, 1) = 1.027;
            Rate = nthroot(FutureValue/app.scenariosTable(6, 1),app.Years-1);
            for i = 2:app.Years-1
                app.scenariosTable(6, i) = app.scenariosTable(6, 1)*((Rate)^(i-1));
            end
            for i = 1:app.Years
                app.scenariosTable(6, i) = app.scenariosTable(6, i)-1;
            end
            app.UITable.Data(6,:) = app.scenariosTable(6,:);
        end

        % Value changed function: RunOnlyOneScenarioDropDown
        function RunOnlyOneScenarioDropDownValueChanged(app, event)
            value = app.RunOnlyOneScenarioDropDown.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetScenariosMenu = uimenu(app.UIFigure);
            app.SetScenariosMenu.Text = 'Set Scenarios';
            app.S1Menu = uimenu(app.SetScenariosMenu);
            app.S1Menu.MenuSelectedFcn = createCallbackFcn(app, @S1MenuSelected, true);
            app.S1Menu.Text = 'S1';
            app.RunS1Menu = uimenu(app.S1Menu);
            app.RunS1Menu.MenuSelectedFcn = createCallbackFcn(app, @RunS1MenuSelected, true);
            app.RunS1Menu.Text = 'Run S1';
            app.S2Menu = uimenu(app.SetScenariosMenu);
            app.S2Menu.Text = 'S2';
            app.RunS2Menu = uimenu(app.S2Menu);
            app.RunS2Menu.Text = 'Run S2';
            app.S3Menu = uimenu(app.SetScenariosMenu);
            app.S3Menu.Text = 'S3';
            app.RunS3Menu = uimenu(app.S3Menu);
            app.RunS3Menu.Text = 'Run S3';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InsertChangesTab = uitab(app.TabGroup);
            app.InsertChangesTab.Title = 'Insert Changes';
            app.PopulationGrowthEditFieldLabel = uilabel(app.InsertChangesTab);
            app.PopulationGrowthEditFieldLabel.Text = 'Population Growth';
            app.PopulationGrowthEditField = uieditfield(app.InsertChangesTab, 'numeric');
            app.PopulationGrowthEditField.Limits = [-1 1];
            app.PopulationGrowthEditField.ValueChangedFcn = createCallbackFcn(app, @PopulationGrowthEditFieldValueChanged, true);
            app.InsertChangesFor2030Label = uilabel(app.InsertChangesTab);
            app.InsertChangesFor2030Label.Text = 'Insert Changes For 2030';
            app.CloseButton_2 = uibutton(app.InsertChangesTab, 'push');
            app.CloseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton_2.Text = 'Close';
            app.ChangeInElectricityConsumptionEditFieldLabel = uilabel(app.InsertChangesTab);
            app.ChangeInElectricityConsumptionEditFieldLabel.Text = 'Change In Electricity Consumption';
            app.ChangeInElectricityConsumptionEditField = uieditfield(app.InsertChangesTab, 'numeric');
            app.ChangeInElectricityConsumptionEditField.Limits = [-1 1];
            app.ChangeInElectricityConsumptionEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeInElectricityConsumptionEditFieldValueChanged, true);
            app.ReduceBeefConsumptionEditFieldLabel = uilabel(app.InsertChangesTab);
            app.ReduceBeefConsumptionEditFieldLabel.Text = 'Reduce Beef Consumption';
            app.ReduceBeefConsumptionEditField = uieditfield(app.InsertChangesTab, 'numeric');
            app.ReduceBeefConsumptionEditField.Limits = [0 1];
            app.ReduceBeefConsumptionEditField.ValueChangedFcn = createCallbackFcn(app, @ReduceBeefConsumptionEditFieldValueChanged, true);
            app.EnergyFromRenewableResourcesEditFieldLabel = uilabel(app.InsertChangesTab);
            app.EnergyFromRenewableResourcesEditFieldLabel.Text = 'Energy From Renewable Resources';
            app.EnergyFromRenewableResourcesEditField = uieditfield(app.InsertChangesTab, 'numeric');
            app.EnergyFromRenewableResourcesEditField.Limits = [0 1];
            app.EnergyFromRenewableResourcesEditField.ValueChangedFcn = createCallbackFcn(app, @EnergyFromRenewableResourcesEditFieldValueChanged, true);
            app.ScenariosTable = uitab(app.TabGroup);
            app.ScenariosTable.Title = 'Scenarios Table';
            app.ScenariosTableLabel = uilabel(app.ScenariosTable);
            app.ScenariosTableLabel.Text = 'Scenarios Table';
            app.RunButton = uibutton(app.ScenariosTable, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UITable = uitable(app.ScenariosTable);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CloseButton = uibutton(app.ScenariosTable, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ScenariosandValuesTab = uitab(app.TabGroup);
            app.ScenariosandValuesTab.Title = 'Scenarios and Values';
            app.ValuesTable = uitable(app.ScenariosandValuesTab);
            app.ValuesTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ValuesTable.RowName = {};
            app.ScenariosandValuesLabel = uilabel(app.ScenariosandValuesTab);
            app.ScenariosandValuesLabel.Text = 'Scenarios and Values';
            app.AddScenarioTab = uitab(app.TabGroup);
            app.AddScenarioTab.Title = 'Add Scenario';
            app.SensitivityAnalysisTab = uitab(app.TabGroup);
            app.SensitivityAnalysisTab.Title = 'Sensitivity Analysis';
            app.SesitivityAnalysisLabel = uilabel(app.SensitivityAnalysisTab);
            app.SesitivityAnalysisLabel.Text = 'Sesitivity Analysis';
            app.RunOnlyOneScenarioDropDownLabel = uilabel(app.SensitivityAnalysisTab);
            app.RunOnlyOneScenarioDropDownLabel.Text = 'Run Only One Scenario';
            app.RunOnlyOneScenarioDropDown = uidropdown(app.SensitivityAnalysisTab);
            app.RunOnlyOneScenarioDropDown.Items = {'Scenario 1', 'Scenario 2', 'Option 3', 'Option 4', 'Option 5'};
            app.RunOnlyOneScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @RunOnlyOneScenarioDropDownValueChanged, true);
            app.RunOnlyOneScenarioDropDown.Value = 'Scenario 1';
            app.RunAllButOneScenarioDropDownLabel = uilabel(app.SensitivityAnalysisTab);
            app.RunAllButOneScenarioDropDownLabel.Text = 'Run All But One Scenario';
            app.RunAllButOneScenarioDropDown = uidropdown(app.SensitivityAnalysisTab);
            app.RunAllButOneScenarioDropDown.Items = {'Scenario 1', 'Scenario 2', 'Option 3', 'Option 4', 'Option 5'};
            app.RunAllButOneScenarioDropDown.Value = 'Scenario 1';
            app.RunSensitivityAnalysisButton = uibutton(app.SensitivityAnalysisTab, 'push');
            app.RunSensitivityAnalysisButton.Text = 'Run Sensitivity Analysis';
            app.CloseButton_3 = uibutton(app.SensitivityAnalysisTab, 'push');
            app.CloseButton_3.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton_3.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SystemUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end